classdef calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalculatorUIFigure  matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        Button_0            matlab.ui.control.Button
        Button_1            matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        Button_3            matlab.ui.control.Button
        Button_4            matlab.ui.control.Button
        Button_5            matlab.ui.control.Button
        Button_6            matlab.ui.control.Button
        Button_7            matlab.ui.control.Button
        Button_8            matlab.ui.control.Button
        Button_9            matlab.ui.control.Button
        Button_decimal      matlab.ui.control.Button
        Button_sign         matlab.ui.control.Button
        Button_plus         matlab.ui.control.Button
        Button_minus        matlab.ui.control.Button
        Button_times        matlab.ui.control.Button
        Button_divide       matlab.ui.control.Button
        Button_exec         matlab.ui.control.Button
        Button_backspace    matlab.ui.control.Button
        Button_clear        matlab.ui.control.Button
        EditField_op1       matlab.ui.control.EditField
        EditField_op2       matlab.ui.control.EditField
        EditField_operator  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        result double % Description
        input char
        operator char
    end
    
    methods (Access = private)
        
        function updateDisplay(app)
            app.EditField_op1.Value = num2str(app.result);
            app.EditField_op2.Value = app.input;
            app.EditField_operator.Value = app.operator;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_0, Button_1, Button_2, 
        % Button_3, Button_4, Button_5, Button_6, Button_7, 
        % Button_8, Button_9
        function Button_inputPushed(app, event)
            app.input = [app.input,event.Source.Text];
            
            app.updateDisplay();
        end

        % Button pushed function: Button_sign
        function Button_signPushed(app, event)
            if isempty(app.input)
                return;
            end
            
            if app.input(1) == '-'
                app.input = app.input(2:end);
            else
                app.input = ['-', app.input];
            end
            
            app.updateDisplay();
        end

        % Button pushed function: Button_decimal
        function Button_decimalPushed(app, event)
            if contains(app.input,'.')
                return;
            end
            
            app.input(end+1) = '.';
            app.updateDisplay();
        end

        % Button pushed function: Button_divide, Button_minus, 
        % Button_plus, Button_times
        function Button_operatorPushed(app, event)
            num = str2double(app.input);
            if isnan(num)
                if isempty(app.operator)
                    num = app.result;
                else
                    return;
                end
            end
            app.result = num;
            app.operator = event.Source.Text;
            app.input = '';
            
            app.updateDisplay();
        end

        % Button pushed function: Button_exec
        function Button_execPushed(app, event)
            if isempty(app.operator)
                return;
            end
            num = str2double(app.input);
            if isnan(num)
                return;
            end
            
            switch app.operator
                case '+'
                    app.result = app.result + num;
                case '-'
                    app.result = app.result - num;
                case '×'
                    app.result = app.result * num;
                case '÷'
                    app.result = app.result / num;
            end
            
            app.operator = '';
            app.input = '';
            
            app.updateDisplay();
        end

        % Button pushed function: Button_clear
        function Button_clearPushed(app, event)
            app.result = [];
            app.input = '';
            app.operator = '';
            
            app.updateDisplay();
        end

        % Button pushed function: Button_backspace
        function Button_backspacePushed(app, event)
            if isempty(app.input)
                app.operator = '';
            else
                app.input = app.input(1:end-1);
            end
            app.updateDisplay();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalculatorUIFigure = uifigure('Visible', 'off');
            app.CalculatorUIFigure.Name = 'Calculator';
            app.GridLayout = uigridlayout(app.CalculatorUIFigure);
            app.Button_0 = uibutton(app.GridLayout, 'push');
            app.Button_0.ButtonPushedFcn = createCallbackFcn(app, @Button_inputPushed, true);
            app.Button_0.Text = '0';
            app.Button_1 = uibutton(app.GridLayout, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_inputPushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_inputPushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_inputPushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_inputPushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_inputPushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_inputPushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_inputPushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_inputPushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_inputPushed, true);
            app.Button_9.Text = '9';
            app.Button_decimal = uibutton(app.GridLayout, 'push');
            app.Button_decimal.ButtonPushedFcn = createCallbackFcn(app, @Button_decimalPushed, true);
            app.Button_decimal.Text = '.';
            app.Button_sign = uibutton(app.GridLayout, 'push');
            app.Button_sign.ButtonPushedFcn = createCallbackFcn(app, @Button_signPushed, true);
            app.Button_sign.Text = '+/-';
            app.Button_plus = uibutton(app.GridLayout, 'push');
            app.Button_plus.ButtonPushedFcn = createCallbackFcn(app, @Button_operatorPushed, true);
            app.Button_plus.Text = '+';
            app.Button_minus = uibutton(app.GridLayout, 'push');
            app.Button_minus.ButtonPushedFcn = createCallbackFcn(app, @Button_operatorPushed, true);
            app.Button_minus.Text = '-';
            app.Button_times = uibutton(app.GridLayout, 'push');
            app.Button_times.ButtonPushedFcn = createCallbackFcn(app, @Button_operatorPushed, true);
            app.Button_times.Text = '×';
            app.Button_divide = uibutton(app.GridLayout, 'push');
            app.Button_divide.ButtonPushedFcn = createCallbackFcn(app, @Button_operatorPushed, true);
            app.Button_divide.Text = '÷';
            app.Button_exec = uibutton(app.GridLayout, 'push');
            app.Button_exec.ButtonPushedFcn = createCallbackFcn(app, @Button_execPushed, true);
            app.Button_exec.Text = '=';
            app.Button_backspace = uibutton(app.GridLayout, 'push');
            app.Button_backspace.ButtonPushedFcn = createCallbackFcn(app, @Button_backspacePushed, true);
            app.Button_backspace.Tooltip = {'Backspace'};
            app.Button_backspace.Text = 'C';
            app.Button_clear = uibutton(app.GridLayout, 'push');
            app.Button_clear.ButtonPushedFcn = createCallbackFcn(app, @Button_clearPushed, true);
            app.Button_clear.Tooltip = {'Clear Everything'};
            app.Button_clear.Text = 'CE';
            app.EditField_op1 = uieditfield(app.GridLayout, 'text');
            app.EditField_op1.Editable = 'off';
            app.EditField_op1.Tooltip = {'Result Register'};
            app.EditField_op2 = uieditfield(app.GridLayout, 'text');
            app.EditField_op2.Editable = 'off';
            app.EditField_op2.Tooltip = {'Input Register'};
            app.EditField_operator = uieditfield(app.GridLayout, 'text');
            app.EditField_operator.Editable = 'off';
            app.EditField_operator.Tooltip = {'Operator Register'};
            % Show the figure after all components are created
            app.CalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calculator

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CalculatorUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.CalculatorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalculatorUIFigure)
        end
    end
end