classdef meshview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        FileEditFieldLabel    matlab.ui.control.Label
        EditField_path        matlab.ui.control.EditField
        Button_browse         matlab.ui.control.Button
        Button_load           matlab.ui.control.Button
        Button_plot           matlab.ui.control.Button
        LoadedEditFieldLabel  matlab.ui.control.Label
        EditField_loaded      matlab.ui.control.EditField
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mesh % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_browse
        function BrowseButtonPushed(app, event)
            if isfile(app.EditField_path.Value)
                default = app.EditField_path.Value;
            else
                default = '';
            end
            
            [file,folder] = uigetfile({'*.obj', 'Wavefront OBJ (*.obj)';'*.*','All Files (*.*)'},"Select File",default);
            if isequal(file,0)
                return;
            end
            path = fullfile(folder,file);
            app.EditField_path.Value = path;
        end

        % Button pushed function: Button_load
        function LoadButtonPushed(app, event)
            if ~isfile(app.EditField_path.Value)
                return;
            end
            
            try
                m = MeshObject.readOBJ(app.EditField_path.Value);
            catch ME
                warndlg("Could not load OBJ file", "Error");
                return;
            end
            
            app.mesh = m;
            app.Button_plot.Enable = "on";
            app.EditField_loaded.Value = app.EditField_path.Value;
        end

        % Button pushed function: Button_plot
        function PlotButtonPushed(app, event)
            if isempty(app.mesh)
                return;
            end
            
            x = [];
            y = [];
            z = [];
            for tri = app.mesh.faces
                tx = [];
                ty = [];
                tz = [];
                for vert = tri.verts
                    c = vert.coords;
                    tx(end+1) = c(1);
                    ty(end+1) = c(2);
                    tz(end+1) = c(3);
                end
                x = [x,tx'];
                y = [y,ty'];
                z = [z,tz'];
            end
            fill3(app.UIAxes,x,y,z,'red');
            
            axis(app.UIAxes,"equal");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Mesh Viewer';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FileEditFieldLabel = uilabel(app.GridLayout);
            app.FileEditFieldLabel.Text = 'File:';
            app.EditField_path = uieditfield(app.GridLayout, 'text');
            app.EditField_path.Editable = 'off';
            app.Button_browse = uibutton(app.GridLayout, 'push');
            app.Button_browse.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.Button_browse.Text = 'Browse';
            app.Button_load = uibutton(app.GridLayout, 'push');
            app.Button_load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Button_load.Text = 'Load';
            app.Button_plot = uibutton(app.GridLayout, 'push');
            app.Button_plot.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Button_plot.Enable = 'off';
            app.Button_plot.Text = 'Plot';
            app.LoadedEditFieldLabel = uilabel(app.GridLayout);
            app.LoadedEditFieldLabel.Text = 'Loaded:';
            app.EditField_loaded = uieditfield(app.GridLayout, 'text');
            app.EditField_loaded.Editable = 'off';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Mesh')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = meshview

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end